classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        title                        matlab.ui.control.Label
        ParticipantIDEditFieldLabel  matlab.ui.control.Label
        ParticipantIDEditField       matlab.ui.control.EditField
        FirstButton                  matlab.ui.control.Button
        SecondButton                 matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        SkiptestButton               matlab.ui.control.Button
        timerLabel                   matlab.ui.control.Label
        unheard                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        freq_list = 30:10:100;
        pref_freq = 1;
        cur_freq = 2;
        mid_amp = 0.1;
        cur_amp = 0.05;
        high_amp = 0.1;
        duration = 1;
        cur_period = randi([0, 1], 1, 1);
        good_answer = 0;
        step = 0;
        max_steps = 15;
        participant_ID = ''
        up_amp_step = 0.005
        down_amp_step = 0.014
        subliminal = 0;
        supraliminal = 0;
    end
    
    methods (Access = private)
        
        function generate_sound(app, freq, amp)
            fs = 8192;
            ts = 1/fs;
            t = 0:ts:app.duration;
            y = amp * sin(2*pi*freq*t);
            sound(y, fs)
        end
    
        function do_freq_test(app)
            app.timerLabel.Visible = 'on';
            pause(1);
            app.timerLabel.Text = '3';
            pause(1);
            app.timerLabel.Text = '2';
            pause(1);
            app.timerLabel.Text = '1';
            pause(1);
            app.timerLabel.Visible = 'off';
            app.timerLabel.Text = 'Get Ready';
            app.cur_period = randi([0, 1], 1, 1);
            if app.cur_period == 0
                app.FirstButton.BackgroundColor = '0, 0.45, 0.74';
                generate_sound(app, app.freq_list(app.pref_freq), app.mid_amp);
                pause(app.duration);
                app.FirstButton.BackgroundColor = '0.94, 0.94, 0.94';
                pause(1);
                app.SecondButton.BackgroundColor = '0, 0.45, 0.74';
                generate_sound(app, app.freq_list(app.cur_freq), app.mid_amp);
                pause(app.duration);
                app.SecondButton.BackgroundColor = '0.94, 0.94, 0.94';
            else
                app.FirstButton.BackgroundColor = '0, 0.45, 0.74';
                generate_sound(app, app.freq_list(app.cur_freq), app.mid_amp);
                pause(app.duration);
                app.FirstButton.BackgroundColor = '0.94, 0.94, 0.94';
                pause(1);
                app.SecondButton.BackgroundColor = '0, 0.45, 0.74';
                generate_sound(app, app.freq_list(app.pref_freq), app.mid_amp);
                pause(app.duration);
                app.SecondButton.BackgroundColor = '0.94, 0.94, 0.94';
            end
            app.cur_freq = app.cur_freq + 1;
            app.FirstButton.Enable = 'on';
            app.SecondButton.Enable = 'on';
        end
    
        function do_amp_test(app)
            app.timerLabel.Visible = 'on';
            pause(1);
            app.timerLabel.Text = '3';
            pause(1);
            app.timerLabel.Text = '2';
            pause(1);
            app.timerLabel.Text = '1';
            pause(1);
            app.timerLabel.Visible = 'off';
            app.timerLabel.Text = 'Get Ready';
            app.cur_amp
            if app.cur_period == 0
               app.FirstButton.BackgroundColor = '0, 0.45, 0.74';
               generate_sound(app, app.freq_list(app.pref_freq), app.cur_amp);
               pause(app.duration);
               app.FirstButton.BackgroundColor = '0.94, 0.94, 0.94';
               pause(1)
               app.SecondButton.BackgroundColor = '0, 0.45, 0.74';
               pause(app.duration)
               app.SecondButton.BackgroundColor = '0.94, 0.94, 0.94';
            else
               app.FirstButton.BackgroundColor = '0, 0.45, 0.74';
               pause(app.duration);
               app.FirstButton.BackgroundColor = '0.94, 0.94, 0.94';  
               pause(1)
               app.SecondButton.BackgroundColor = '0, 0.45, 0.74';             
               generate_sound(app, app.freq_list(app.pref_freq), app.cur_amp);
               pause(app.duration);
               app.SecondButton.BackgroundColor = '0.94, 0.94, 0.94';
            end
        end
    
        function amp_1(app, scr, event)
            app.FirstButton.Enable = 'off';
            app.SecondButton.Enable = 'off';
            app.unheard.Enable = 'off';
            if (app.cur_period == 0)
                if (app.good_answer == 1)
                    app.supraliminal = app.cur_amp;
                    app.cur_amp = app.cur_amp - app.down_amp_step;
                    if app.cur_amp < app.down_amp_step
                        app.down_amp_step = app.down_amp_step / 2;
                        app.up_amp_step = app.up_amp_step / 2;
                        app.cur_amp = app.down_amp_step;
                    end
                    app.good_answer = 0;
                else
                    app.good_answer = 1;
                end
            else
                app.subliminal = app.cur_amp;
                app.good_answer = 0;
                app.cur_amp = app.cur_amp + app.up_amp_step;
            end
            app.cur_period = randi([0, 1], 1, 1);
            app.step = app.step + 1;
            app.step
            if (app.step >= app.max_steps)
                finish_test(app);
            else
                do_amp_test(app);
                app.FirstButton.Enable = 'on';
                app.SecondButton.Enable = 'on';
                app.unheard.Enable = 'on';
            end
        end
    
        function amp_2(app, scr, event)
            app.FirstButton.Enable = 'off';
            app.SecondButton.Enable = 'off';
            app.unheard.Enable = 'off';
            if (app.cur_period == 1)
                if (app.good_answer == 1)
                    app.supraliminal = app.cur_amp;
                    app.cur_amp = app.cur_amp - app.down_amp_step;
                    if app.cur_amp < app.down_amp_step
                        app.down_amp_step = app.down_amp_step / 2;
                        app.up_amp_step = app.up_amp_step / 2;
                        app.cur_amp = app.down_amp_step;
                    end
                    app.good_answer = 0;
                else
                    app.good_answer = 1;
                end
            else
                app.subliminal = app.cur_amp;
                app.good_answer = 0;
                app.cur_amp = app.cur_amp + app.up_amp_step;
            end
            app.cur_period = randi([0, 1], 1, 1);
            app.step = app.step + 1;
            app.step
            if (app.step >= app.max_steps)
                finish_test(app);
            else
                do_amp_test(app);
                app.FirstButton.Enable = 'on';
                app.SecondButton.Enable = 'on';
                app.unheard.Enable = 'on';
            end
        end
    
        function start_amp_test(app, src, event)
            app.StartButton.Enable = 'off';
            app.StartButton.Visible = 'off';
            app.title.Text = {'Was there a signal in the first or second time period?'};
            app.FirstButton.ButtonPushedFcn = @app.amp_1;
            app.SecondButton.ButtonPushedFcn = @app.amp_2;
            app.title.Visible = 'on';
            app.FirstButton.Visible = 'on';
            app.SecondButton.Visible = 'on';
            pause(1)
            do_amp_test(app);
            
            app.FirstButton.Enable = 'on';
            app.SecondButton.Enable = 'on';
            app.SkiptestButton.Visible = 'on';
            app.SkiptestButton.Enable = 'on';
            app.unheard.Enable = 'on';
            app.unheard.Visible = 'on';
        end
    
        function finish_freq_test(app)
            app.StartButton.Visible = 'on';
            app.StartButton.Enable = 'on';
            app.StartButton.ButtonPushedFcn = @app.start_amp_test;
            app.SkiptestButton.ButtonPushedFcn = @end_task_button;
            app.SkiptestButton.Text = 'End task';
            app.SkiptestButton.Visible = 'off';
            app.SkiptestButton.Enable = 'off';
            app.title.Text = {'Get ready for amplitude threshold test.'};
            app.FirstButton.Visible = 'off';
            app.SecondButton.Visible = 'off';
            app.FirstButton.Enable = 'off';
            app.SecondButton.Enable = 'off';
        end
    
        function end_task_button(app, src, event)
            finish_test(app);
        end
    
        function finish_test(app)
           fprintf('Below threshold amplitude: %d\n', 100 * app.subliminal);
           fprintf('Above threshold amplitude: %d\n', 100 * app.supraliminal);
           fprintf('Prefered frequency: %d\n', app.freq_list(app.pref_freq));
           if exist(app.participant_ID, 'dir') ~= 7
               mkdir(app.participant_ID)
           end
           fid = fopen(strcat(app.participant_ID, '\', app.participant_ID, '_freq_amp.txt'), 'w');
           fprintf(fid, 'Subliminal amplitude: %d\n', 100 * app.subliminal);
           fprintf('Supraliminal amplitude: %d\n', 300 * app.supraliminal);
           fprintf(fid, 'Prefered frequency: %d\n', app.freq_list(app.pref_freq));
           fprintf(fid, strrep(['Freqlist: (' sprintf(' %d,', app.freq_list) ')'], ',)', ')'));
           fclose(fid);
           delete(app);
        end
            
    end
    

    methods (Access = private)

        % Callback function
        function StartButtonValueChanged(app, event)
            
        end

        % Value changed function: ParticipantIDEditField
        function ParticipantIDEditFieldValueChanged(app, event)
            app.StartButton.Enable = 'on';
        end

        % Button pushed function: FirstButton
        function FirstButtonPushed(app, event)
            app.FirstButton.Enable = 'off';
            app.SecondButton.Enable = 'off';
            if(app.cur_freq <= length(app.freq_list))
                if app.cur_period == 1
                    app.pref_freq = app.cur_freq - 1;
                end
                do_freq_test(app);
            else
                finish_freq_test(app);
            end
        end

        % Button pushed function: SecondButton
        function SecondButtonPushed(app, event)
            app.FirstButton.Enable = 'off';
            app.SecondButton.Enable = 'off';
            if(app.cur_freq <= length(app.freq_list))
                if app.cur_period == 0
                    app.pref_freq = app.cur_freq - 1;
                end
                do_freq_test(app);
            else
                finish_freq_test(app);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.participant_ID = app.ParticipantIDEditField.Value;
            
            app.freq_list = app.freq_list(randperm(length(app.freq_list)));
            app.StartButton.Enable = 'off';
            app.StartButton.Visible = 'off';
            delete(app.ParticipantIDEditField)
            delete(app.ParticipantIDEditFieldLabel)
            app.title.Visible = 'on';
            app.FirstButton.Visible = 'on';
            app.SecondButton.Visible = 'on';
            pause(1)
            do_freq_test(app)
            
            app.FirstButton.Enable = 'on';
            app.SecondButton.Enable = 'on';
            
            app.SkiptestButton.Visible = 'on';
            app.SkiptestButton.Enable = 'on';
        end

        % Button pushed function: SkiptestButton
        function SkiptestButtonPushed(app, event)
            finish_freq_test(app);
        end

        % Button pushed function: unheard
        function unheardButtonPushed(app, event)
            app.FirstButton.Enable = 'off';
            app.SecondButton.Enable = 'off';
            app.unheard.Enable = 'off';
            app.subliminal = app.cur_amp;
            app.good_answer = 0;
            app.cur_amp = app.cur_amp + app.up_amp_step;
            app.cur_period = randi([0, 1], 1, 1);
            app.step = app.step + 1;
            app.step
            if (app.step >= app.max_steps)
                finish_test(app);
            else
                do_amp_test(app);
                app.FirstButton.Enable = 'on';
                app.SecondButton.Enable = 'on';
                app.unheard.Enable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.title = uilabel(app.UIFigure);
            app.title.Visible = 'off';
            app.title.Text = {'Out of the two signals that you felt, which one '; 'would you mind the least having on for a several minutes'};
            app.ParticipantIDEditFieldLabel = uilabel(app.UIFigure);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.UIFigure, 'text');
            app.ParticipantIDEditField.ValueChangedFcn = createCallbackFcn(app, @ParticipantIDEditFieldValueChanged, true);
            app.FirstButton = uibutton(app.UIFigure, 'push');
            app.FirstButton.ButtonPushedFcn = createCallbackFcn(app, @FirstButtonPushed, true);
            app.FirstButton.Enable = 'off';
            app.FirstButton.Visible = 'off';
            app.FirstButton.Text = 'First';
            app.SecondButton = uibutton(app.UIFigure, 'push');
            app.SecondButton.ButtonPushedFcn = createCallbackFcn(app, @SecondButtonPushed, true);
            app.SecondButton.Enable = 'off';
            app.SecondButton.Visible = 'off';
            app.SecondButton.Text = 'Second';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.SkiptestButton = uibutton(app.UIFigure, 'push');
            app.SkiptestButton.ButtonPushedFcn = createCallbackFcn(app, @SkiptestButtonPushed, true);
            app.SkiptestButton.Enable = 'off';
            app.SkiptestButton.Visible = 'off';
            app.SkiptestButton.Text = 'Skip test';
            app.timerLabel = uilabel(app.UIFigure);
            app.timerLabel.Enable = 'off';
            app.timerLabel.Visible = 'off';
            app.timerLabel.Text = 'Get Ready';
            app.unheard = uibutton(app.UIFigure, 'push');
            app.unheard.ButtonPushedFcn = createCallbackFcn(app, @unheardButtonPushed, true);
            app.unheard.Enable = 'off';
            app.unheard.Visible = 'off';
            app.unheard.Text = 'I don''t know';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end